classdef gold_guesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoldGuesserUIFigure  matlab.ui.Figure
        Result               matlab.ui.control.Label
        SuccessValue         matlab.ui.control.Label
        AttemptValue         matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttempsLabel         matlab.ui.control.Label
        gold                 matlab.ui.control.Image
        leprechaun           matlab.ui.control.Image
        Button3              matlab.ui.control.Button
        Button2              matlab.ui.control.Button
        Button1              matlab.ui.control.Button
    end


    properties (Access = private)
        attempts = 0
        successes = 0
        leprechaun_positions
        gold_positions
    end

    methods (Access = private)

        function guess(app, leprechaunPosition)
            app.leprechaun.Position = app.leprechaun_positions(leprechaunPosition, :);
            goldPosition = randi([1, 3]);
            app.gold.Position = app.gold_positions(goldPosition, :);
            app.attempts = app.attempts + 1;
            if goldPosition == leprechaunPosition
                app.Result.Text = "Success!";
                app.successes = app.successes + 1;
            else
                app.Result.Text = "Failure";
            end
            app.AttemptValue.Text = string(app.attempts);
            app.SuccessValue.Text = string(app.successes);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.leprechaun_positions(1,:) = [93,261,100,100];
            app.leprechaun_positions(2,:) = [271,261,100,100];
            app.leprechaun_positions(3,:) = [449,253,100,100];

            app.gold_positions(1,:) = [93,41,100,100];
            app.gold_positions(2,:) = [271,45,100,100];
            app.gold_positions(3,:) = [449,52,100,100];

        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            app.guess(1)
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            app.guess(2)
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            app.guess(3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GoldGuesserUIFigure = uifigure('Visible', 'off');
            app.GoldGuesserUIFigure.Name = 'Gold Guesser';
            app.Button1 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.Button3 = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '3';
            app.leprechaun = uiimage(app.GoldGuesserUIFigure);
            app.leprechaun.ImageSource = 'leprechaun.png';
            app.gold = uiimage(app.GoldGuesserUIFigure);
            app.gold.ImageSource = 'gold.png';
            app.AttempsLabel = uilabel(app.GoldGuesserUIFigure);
            app.AttempsLabel.Text = 'Attemps:';
            app.SuccessLabel = uilabel(app.GoldGuesserUIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.AttemptValue = uilabel(app.GoldGuesserUIFigure);
            app.AttemptValue.Text = '0';
            app.SuccessValue = uilabel(app.GoldGuesserUIFigure);
            app.SuccessValue.Text = '0';
            app.Result = uilabel(app.GoldGuesserUIFigure);
            app.Result.Text = 'Success!';
            % Show the figure after all components are created
            app.GoldGuesserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gold_guesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoldGuesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoldGuesserUIFigure)
        end
    end
end