classdef MQTTCarGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MQTTCarGUIUIFigure           matlab.ui.Figure
        MiscPanel                    matlab.ui.container.Panel
        VLabel                       matlab.ui.control.Label
        UpdateVoltageButton          matlab.ui.control.Button
        BatteryVoltageLabel          matlab.ui.control.Label
        BeepButton                   matlab.ui.control.Button
        LEDsPanel                    matlab.ui.container.Panel
        AllOffButton                 matlab.ui.control.Button
        SendCurrentButton            matlab.ui.control.Button
        SelectDeselectAllButton      matlab.ui.control.Button
        SendColorButton              matlab.ui.control.Button
        CarImage                     matlab.ui.control.Image
        LED7                         matlab.ui.control.CheckBox
        LED6                         matlab.ui.control.CheckBox
        LED5                         matlab.ui.control.CheckBox
        LED4                         matlab.ui.control.CheckBox
        LED3                         matlab.ui.control.CheckBox
        LED2                         matlab.ui.control.CheckBox
        LED1                         matlab.ui.control.CheckBox
        LED0                         matlab.ui.control.CheckBox
        ModesPanel                   matlab.ui.container.Panel
        StopLightFollowingButton     matlab.ui.control.Button
        StartLightFollowingButton    matlab.ui.control.Button
        StopLineFollowingButton      matlab.ui.control.Button
        StartLineFollowingButton     matlab.ui.control.Button
        DriveUntilLineButton         matlab.ui.control.Button
        DriveUntilWallButton         matlab.ui.control.Button
        SensorStreamingPanel         matlab.ui.container.Panel
        TimestampLabel               matlab.ui.control.Label
        RightLamp                    matlab.ui.control.Lamp
        RightLampLabel               matlab.ui.control.Label
        CenterLamp                   matlab.ui.control.Lamp
        CenterLampLabel              matlab.ui.control.Label
        LeftLamp                     matlab.ui.control.Lamp
        LeftLampLabel                matlab.ui.control.Label
        StreamingSensorValuesSwitch  matlab.ui.control.Switch
        StreamingSensorValuesSwitchLabel  matlab.ui.control.Label
        SpeedSlider                  matlab.ui.control.Slider
        SpeedSliderLabel             matlab.ui.control.Label
        ServoHeadPanel               matlab.ui.container.Panel
        TiltSlider                   matlab.ui.control.Slider
        TiltSliderLabel              matlab.ui.control.Label
        PanSlider                    matlab.ui.control.Slider
        PanSliderLabel               matlab.ui.control.Label
        ClickbelowintheblankareatousedrivekeysLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        color = [0 0 0]
        lastKeyPressed = ""
    end
    
    methods (Access = private)
        
       function sendMessage(app, messageType, payload)
           messageStruct.type = messageType;
           if nargin > 2
            messageStruct.payload = payload;
           end
           message = jsonencode(messageStruct);
           fprintf("Sending: %s\n", message);
           app.mqClient.write("me435/fisherds/to_pi", message);
       end

        
      function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

%             if messageType == "something_with_a_payload"
%                     payload = messageStruct.payload;
%             end

            if strcmp(messageType, "voltage")
                batVoltage = messageStruct.payload
                batString = sprintf("%.2f v", batVoltage)
                app.VLabel.Text = batString
            end


        end

        function sendCurrentColor(app)
                red_value = round(app.color(1) * 255);
                green_value = round(app.color(2) * 255);
                blue_value = round(app.color(3) * 255);

                if app.LED0.Value && app.LED1.Value && ...
                    app.LED2.Value && app.LED3.Value && ...
                    app.LED4.Value && app.LED5.Value && ...
                    app.LED6.Value && app.LED7.Value
                    app.sendMessage("set_all_leds", [red_value green_value blue_value])
                else
                    if app.LED0.Value
                        app.sendMessage("set_led", [0 red_value green_value blue_value])
                    end
                    if app.LED1.Value
                        app.sendMessage("set_led", [1 red_value green_value blue_value])
                    end
                    if app.LED2.Value
                        app.sendMessage("set_led", [2 red_value green_value blue_value])
                    end
                    if app.LED3.Value
                        app.sendMessage("set_led", [3 red_value green_value blue_value])
                    end
                    if app.LED4.Value
                        app.sendMessage("set_led", [4 red_value green_value blue_value])
                    end
                    if app.LED5.Value
                        app.sendMessage("set_led", [5 red_value green_value blue_value])
                    end
                    if app.LED6.Value
                        app.sendMessage("set_led", [6 red_value green_value blue_value])
                    end
                    if app.LED7.Value
                        app.sendMessage("set_led", [7 red_value green_value blue_value])
                    end
                end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
%             app.mqClient = mqttclient("tcp://test.mosquitto.org");
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            subscribe(app.mqClient, "me435/fisherds/#", "Callback", @app.myCallback)
        end

        % Button pushed function: SendColorButton
        function SendColorButtonPushed(app, event)
            c = uisetcolor();  % Opens the color picker
            if length(c) == 3
                app.LEDsPanel.BackgroundColor = c;
                app.color = c;
                app.sendCurrentColor();
            end
        end

        % Button pushed function: SelectDeselectAllButton
        function SelectDeselectAllButtonPushed(app, event)
            if app.LED0.Value && app.LED1.Value && ...
                app.LED2.Value && app.LED3.Value && ...
                app.LED4.Value && app.LED5.Value && ...
                app.LED6.Value && app.LED7.Value
            app.LED7.Value = false;
            app.LED6.Value = false;
            app.LED5.Value = false;
            app.LED4.Value = false;
            app.LED3.Value = false;
            app.LED2.Value = false;
            app.LED1.Value = false;
            app.LED0.Value = false;
            else
            app.LED7.Value = true;
            app.LED6.Value = true;
            app.LED5.Value = true;
            app.LED4.Value = true;
            app.LED3.Value = true;
            app.LED2.Value = true;
            app.LED1.Value = true;
            app.LED0.Value = true;
            end
        end

        % Button pushed function: SendCurrentButton
        function SendCurrentButtonPushed(app, event)
            app.sendCurrentColor();
        end

        % Button pushed function: AllOffButton
        function AllOffButtonPushed(app, event)
            app.color = [0 0 0];
            app.LEDsPanel.BackgroundColor = [0.94,0.94,0.94];
            app.sendMessage("set_all_leds", [0 0 0])
        end

        % Key press function: MQTTCarGUIUIFigure
        function MQTTCarGUIUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.lastKeyPressed)
                return;  % no need to send the same thing again if held.
            end
            app.lastKeyPressed = key;
            speed = app.SpeedSlider.Value;

            if key == "uparrow"
                app.sendMessage("set_speeds", [speed speed speed speed])
            end
            if key == "downarrow"
                app.sendMessage("set_speeds", [-speed -speed -speed -speed])
            end
            if key == "leftarrow"
                app.sendMessage("set_speeds", [-speed speed speed -speed])
            end
            if key == "rightarrow"
                app.sendMessage("set_speeds", [speed -speed -speed speed])
            end
            if key == "a"
                app.sendMessage("set_speeds", [-speed/2 -speed/2 speed/2 speed/2])
            end
            if key == "d"
                app.sendMessage("set_speeds", [speed/2 speed/2 -speed/2 -speed/2])
            end
            if key == "w"
                app.sendMessage("left", 90)
            end
            if key == "s"
                app.sendMessage("right", 90)
            end
        end

        % Key release function: MQTTCarGUIUIFigure
        function MQTTCarGUIUIFigureKeyRelease(app, event)
            key = event.Key;
            app.lastKeyPressed = "";
            app.sendMessage("stop")
        end

        % Value changing function: PanSlider
        function PanSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendMessage("pan", changingValue)
        end

        % Value changing function: TiltSlider
        function TiltSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendMessage("tilt", changingValue)
        end

        % Button pushed function: UpdateVoltageButton
        function UpdateVoltageButtonPushed(app, event)
            
            app.sendMessage("get_voltage")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MQTTCarGUIUIFigure = uifigure('Visible', 'off');
            app.MQTTCarGUIUIFigure.Name = 'MQTT Car GUI';
            app.MQTTCarGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @MQTTCarGUIUIFigureKeyPress, true);
            app.MQTTCarGUIUIFigure.KeyReleaseFcn = createCallbackFcn(app, @MQTTCarGUIUIFigureKeyRelease, true);
            app.ClickbelowintheblankareatousedrivekeysLabel = uilabel(app.MQTTCarGUIUIFigure);
            app.ClickbelowintheblankareatousedrivekeysLabel.Text = 'Click below in the blank area to use drive keys';
            app.ServoHeadPanel = uipanel(app.MQTTCarGUIUIFigure);
            app.ServoHeadPanel.Title = 'Servo Head';
            app.PanSliderLabel = uilabel(app.ServoHeadPanel);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.ServoHeadPanel);
            app.PanSlider.Limits = [0 180];
            app.PanSlider.ValueChangingFcn = createCallbackFcn(app, @PanSliderValueChanging, true);
            app.PanSlider.Value = 90;
            app.TiltSliderLabel = uilabel(app.ServoHeadPanel);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.ServoHeadPanel);
            app.TiltSlider.Limits = [0 180];
            app.TiltSlider.ValueChangingFcn = createCallbackFcn(app, @TiltSliderValueChanging, true);
            app.TiltSlider.Value = 90;
            app.SpeedSliderLabel = uilabel(app.MQTTCarGUIUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.MQTTCarGUIUIFigure);
            app.SpeedSlider.Value = 40;
            app.SensorStreamingPanel = uipanel(app.MQTTCarGUIUIFigure);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.StreamingSensorValuesSwitchLabel = uilabel(app.SensorStreamingPanel);
            app.StreamingSensorValuesSwitchLabel.Text = 'Streaming Sensor Values';
            app.StreamingSensorValuesSwitch = uiswitch(app.SensorStreamingPanel, 'slider');
            app.LeftLampLabel = uilabel(app.SensorStreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.SensorStreamingPanel);
            app.CenterLampLabel = uilabel(app.SensorStreamingPanel);
            app.CenterLampLabel.Text = 'Center';
            app.CenterLamp = uilamp(app.SensorStreamingPanel);
            app.RightLampLabel = uilabel(app.SensorStreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.SensorStreamingPanel);
            app.TimestampLabel = uilabel(app.SensorStreamingPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.ModesPanel = uipanel(app.MQTTCarGUIUIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.StartLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLineFollowingButton.Text = 'Start Line Following';
            app.StopLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLineFollowingButton.Text = 'Stop Line Following';
            app.StartLightFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLightFollowingButton.Text = 'Start Light Following';
            app.StopLightFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLightFollowingButton.Text = 'Stop Light Following';
            app.LEDsPanel = uipanel(app.MQTTCarGUIUIFigure);
            app.LEDsPanel.Title = 'LEDs';
            app.LED0 = uicheckbox(app.LEDsPanel);
            app.LED0.Text = '0';
            app.LED0.Value = true;
            app.LED1 = uicheckbox(app.LEDsPanel);
            app.LED1.Text = '1';
            app.LED2 = uicheckbox(app.LEDsPanel);
            app.LED2.Text = '2';
            app.LED3 = uicheckbox(app.LEDsPanel);
            app.LED3.Text = '3';
            app.LED4 = uicheckbox(app.LEDsPanel);
            app.LED4.Text = '4';
            app.LED5 = uicheckbox(app.LEDsPanel);
            app.LED5.Text = '5';
            app.LED6 = uicheckbox(app.LEDsPanel);
            app.LED6.Text = '6';
            app.LED7 = uicheckbox(app.LEDsPanel);
            app.LED7.Text = '7';
            app.CarImage = uiimage(app.LEDsPanel);
            app.CarImage.ImageSource = fullfile(pathToMLAPP, 'leds.png');
            app.SendColorButton = uibutton(app.LEDsPanel, 'push');
            app.SendColorButton.ButtonPushedFcn = createCallbackFcn(app, @SendColorButtonPushed, true);
            app.SendColorButton.Text = 'Send Color';
            app.SelectDeselectAllButton = uibutton(app.LEDsPanel, 'push');
            app.SelectDeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDeselectAllButtonPushed, true);
            app.SelectDeselectAllButton.Text = 'Select/Deselect All';
            app.SendCurrentButton = uibutton(app.LEDsPanel, 'push');
            app.SendCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @SendCurrentButtonPushed, true);
            app.SendCurrentButton.Text = 'Send Current';
            app.AllOffButton = uibutton(app.LEDsPanel, 'push');
            app.AllOffButton.ButtonPushedFcn = createCallbackFcn(app, @AllOffButtonPushed, true);
            app.AllOffButton.Text = 'All Off';
            app.MiscPanel = uipanel(app.MQTTCarGUIUIFigure);
            app.MiscPanel.Title = 'Misc';
            app.BeepButton = uibutton(app.MiscPanel, 'push');
            app.BeepButton.Text = 'Beep';
            app.BatteryVoltageLabel = uilabel(app.MiscPanel);
            app.BatteryVoltageLabel.Text = 'Battery Voltage';
            app.UpdateVoltageButton = uibutton(app.MiscPanel, 'push');
            app.UpdateVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVoltageButtonPushed, true);
            app.UpdateVoltageButton.Text = 'Update Voltage';
            app.VLabel = uilabel(app.MiscPanel);
            app.VLabel.Text = '99.99 V';
            % Show the figure after all components are created
            app.MQTTCarGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MQTTCarGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MQTTCarGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MQTTCarGUIUIFigure)
        end
    end
end