classdef mqtt_matlab_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MQTTGPIOUIFigure   matlab.ui.Figure
        SendLedsButton     matlab.ui.control.Button
        GreenSwitch        matlab.ui.control.ToggleSwitch
        GreenSwitchLabel   matlab.ui.control.Label
        YellowSwitch       matlab.ui.control.ToggleSwitch
        YellowSwitchLabel  matlab.ui.control.Label
        RedSwitch          matlab.ui.control.ToggleSwitch
        RedSwitchLabel     matlab.ui.control.Label
        CounterLabel       matlab.ui.control.Label
        GreenOffButton     matlab.ui.control.Button
        GreenOnButton      matlab.ui.control.Button
        YellowOffButton    matlab.ui.control.Button
        YellowOnButton     matlab.ui.control.Button
        RedOffButton       matlab.ui.control.Button
        RedOnButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
       function sendMessage(app, messageType, payload)
           messageStruct.type = messageType;
           messageStruct.payload = payload;
           message = jsonencode(messageStruct);
           fprintf("Sending: %s\n", message);
           app.mqClient.write("me435/fisherds/to_pi", message);
       end

        
      function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

%             if messageType == "something_with_a_payload"
%                     payload = messageStruct.payload;
%             end

            if messageType == "button"
                    app.counter = app.counter + 1;
                    app.CounterLabel.Text = num2str(app.counter);
            end
            if messageType == "reset"
                    app.counter = 0;
                    app.CounterLabel.Text = num2str(app.counter);
            end


        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://test.mosquitto.org");
            subscribe(app.mqClient, "me435/fisherds/#", "Callback", @app.myCallback)
        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
            app.sendMessage("red", "on");
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            app.sendMessage("red", "off");
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
            app.sendMessage("yellow", 1);
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
            app.sendMessage("yellow", 0);
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
            app.sendMessage("green", true);
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
            app.sendMessage("green", false);
        end

        % Button pushed function: SendLedsButton
        function SendLedsButtonPushed(app, event)
            red = str2num(app.RedSwitch.Value);
            yellow = str2num(app.YellowSwitch.Value);
            green = str2num(app.GreenSwitch.Value);
            ledValues = [red yellow green];
            app.sendMessage("leds", ledValues);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MQTTGPIOUIFigure = uifigure('Visible', 'off');
            app.MQTTGPIOUIFigure.Name = 'MQTT GPIO MATLAB App';
            app.RedOnButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'Red On';
            app.RedOffButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Red Off';
            app.YellowOnButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'Yellow On';
            app.YellowOffButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOnButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'Green On';
            app.GreenOffButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Green Off';
            app.CounterLabel = uilabel(app.MQTTGPIOUIFigure);
            app.CounterLabel.Text = '0';
            app.RedSwitchLabel = uilabel(app.MQTTGPIOUIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.MQTTGPIOUIFigure, 'toggle');
            app.RedSwitch.Items = {'0', '1'};
            app.RedSwitch.Value = '1';
            app.YellowSwitchLabel = uilabel(app.MQTTGPIOUIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.MQTTGPIOUIFigure, 'toggle');
            app.YellowSwitch.Items = {'0', '1'};
            app.YellowSwitch.Value = '1';
            app.GreenSwitchLabel = uilabel(app.MQTTGPIOUIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.MQTTGPIOUIFigure, 'toggle');
            app.GreenSwitch.Items = {'0', '1'};
            app.GreenSwitch.Value = '1';
            app.SendLedsButton = uibutton(app.MQTTGPIOUIFigure, 'push');
            app.SendLedsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLedsButtonPushed, true);
            app.SendLedsButton.Text = 'Send Leds';
            % Show the figure after all components are created
            app.MQTTGPIOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mqtt_matlab_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MQTTGPIOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MQTTGPIOUIFigure)
        end
    end
end