classdef lab5GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab5CarMQTTGUIUIFigure      matlab.ui.Figure
        ModesPanel                  matlab.ui.container.Panel
        StopLightFollowingButton    matlab.ui.control.Button
        StartLightFollowingButton   matlab.ui.control.Button
        StopLineFollowingButton     matlab.ui.control.Button
        StartLineFollowingButton    matlab.ui.control.Button
        DriveUntilLineButton        matlab.ui.control.Button
        DriveUntilWallButton        matlab.ui.control.Button
        SensorStreamingPanel        matlab.ui.container.Panel
        TimestampLabel              matlab.ui.control.Label
        RightLamp                   matlab.ui.control.Lamp
        RightLampLabel              matlab.ui.control.Label
        MiddleLamp                  matlab.ui.control.Lamp
        MiddleLampLabel             matlab.ui.control.Label
        LeftLamp                    matlab.ui.control.Lamp
        LeftLampLabel               matlab.ui.control.Label
        SensorStreamingSwitch       matlab.ui.control.Switch
        SensorStreamingSwitchLabel  matlab.ui.control.Label
        MiscPanel                   matlab.ui.container.Panel
        UpdateVoltageButton         matlab.ui.control.Button
        vLabel                      matlab.ui.control.Label
        BatterVoltageLabel          matlab.ui.control.Label
        BeepButton                  matlab.ui.control.Button
        ServoHeadPanel              matlab.ui.container.Panel
        TiltSlider                  matlab.ui.control.Slider
        TiltSliderLabel             matlab.ui.control.Label
        PanSlider                   matlab.ui.control.Slider
        PanSliderLabel              matlab.ui.control.Label
        SpeedSlider                 matlab.ui.control.Slider
        SpeedSliderLabel            matlab.ui.control.Label
        ClickintheareabelowthenusekeyboardkeystodriveLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        mqClient
        priorKey = ''
    end

    methods (Access = private)

        function sendMessage(app, messageType, payload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = payload;
            end
            message = jsonencode(messageStruct);
            fprintf("Sending: %s\n", message);
            app.mqClient.write("me435/fisherds/to_pi", message);
        end


        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;
            %             if messageType == "something_with_a_payload"
            %                     payload = messageStruct.payload;
            %             end

            if strcmp(messageType, "line_sensor_reading")
                payload = messageStruct.payload;
                timestamp = payload.timestamp;
                leftValue = payload.left;
                middleValue = payload.middle;
                rightValue = payload.right;

                app.TimestampLabel.Text = timestamp;
                whiteValue = [1 1 1];
                blackValue = [0 0 0];
                if strcmp(leftValue, "W")
                    app.LeftLamp.Color = whiteValue;
                else
                    app.LeftLamp.Color = blackValue;
                end
                if strcmp(middleValue, "W")
                    app.MiddleLamp.Color = whiteValue;
                else
                    app.MiddleLamp.Color = blackValue;
                end
                if strcmp(rightValue, "W")
                    app.RightLamp.Color = whiteValue;
                else
                    app.RightLamp.Color = blackValue;
                end

            end

            if strcmp(messageType, "voltage")
                payload = messageStruct.payload;
                batteryVoltage = payload
            end


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
%             app.mqClient = mqttclient("tcp://test.mosquitto.org");
%             app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            subscribe(app.mqClient, "me435/fisherds/#", "Callback", @app.myCallback)
        end

        % Key press function: Lab5CarMQTTGUIUIFigure
        function Lab5CarMQTTGUIUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return;
            end
            app.priorKey = key;
            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed speed speed speed])
            end
            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed -speed -speed -speed])
            end
            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed speed speed -speed])
            end
            if strcmp(key, "rightarrow")

                app.sendMessage("set_speeds", [speed -speed -speed speed])
            end
            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed/2 -speed/2 speed/2 speed/2])
            end
            if strcmp(key, "d")
                app.sendMessage("set_speeds", [speed/2 speed/2 -speed/2 -speed/2])
            end

        end

        % Key release function: Lab5CarMQTTGUIUIFigure
        function Lab5CarMQTTGUIUIFigureKeyRelease(app, event)
            fprintf("Send Stop\n");
            app.priorKey = "";
            app.sendMessage("stop")

        end

        % Value changing function: PanSlider
        function PanSliderValueChanging(app, event)
            panValue = round(event.Value);
            app.sendMessage("pan", panValue);
        end

        % Value changing function: TiltSlider
        function TiltSliderValueChanging(app, event)
            tiltValue = round(event.Value);
            app.sendMessage("tilt", tiltValue);
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep")
        end

        % Value changed function: SensorStreamingSwitch
        function SensorStreamingSwitchValueChanged(app, event)
            value = app.SensorStreamingSwitch.Value;
            if strcmp(value, "On")
                app.sendMessage("is_streaming", true);
            else
                app.sendMessage("is_streaming", false);
            end
            
        end

        % Button pushed function: DriveUntilWallButton
        function DriveUntilWallButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_wall");
        end

        % Button pushed function: DriveUntilLineButton
        function DriveUntilLineButtonPushed(app, event)
            app.sendMessage("mode", "drive_until_line");
        end

        % Button pushed function: StartLineFollowingButton
        function StartLineFollowingButtonPushed(app, event)
            app.sendMessage("mode", "line_following");
        end

        % Button pushed function: StopLineFollowingButton
        function StopLineFollowingButtonPushed(app, event)
            app.sendMessage("mode", "none");
        end

        % Button pushed function: StartLightFollowingButton
        function StartLightFollowingButtonPushed(app, event)
            app.sendMessage("mode", "light_following");
        end

        % Button pushed function: StopLightFollowingButton
        function StopLightFollowingButtonPushed(app, event)
            app.sendMessage("mode", "none");
        end

        % Button pushed function: UpdateVoltageButton
        function UpdateVoltageButtonPushed(app, event)
            app.sendMessage("get_voltage");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab5CarMQTTGUIUIFigure = uifigure('Visible', 'off');
            app.Lab5CarMQTTGUIUIFigure.Name = 'Lab 5 Car MQTT GUI';
            app.Lab5CarMQTTGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @Lab5CarMQTTGUIUIFigureKeyPress, true);
            app.Lab5CarMQTTGUIUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab5CarMQTTGUIUIFigureKeyRelease, true);
            app.ClickintheareabelowthenusekeyboardkeystodriveLabel = uilabel(app.Lab5CarMQTTGUIUIFigure);
            app.ClickintheareabelowthenusekeyboardkeystodriveLabel.Text = 'Click in the area below, then use keyboard keys to drive';
            app.SpeedSliderLabel = uilabel(app.Lab5CarMQTTGUIUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab5CarMQTTGUIUIFigure);
            app.SpeedSlider.Value = 50;
            app.ServoHeadPanel = uipanel(app.Lab5CarMQTTGUIUIFigure);
            app.ServoHeadPanel.Title = 'Servo Head';
            app.PanSliderLabel = uilabel(app.ServoHeadPanel);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.ServoHeadPanel);
            app.PanSlider.Limits = [20 160];
            app.PanSlider.ValueChangingFcn = createCallbackFcn(app, @PanSliderValueChanging, true);
            app.PanSlider.Value = 90;
            app.TiltSliderLabel = uilabel(app.ServoHeadPanel);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.ServoHeadPanel);
            app.TiltSlider.Limits = [70 160];
            app.TiltSlider.ValueChangingFcn = createCallbackFcn(app, @TiltSliderValueChanging, true);
            app.TiltSlider.Value = 90;
            app.MiscPanel = uipanel(app.Lab5CarMQTTGUIUIFigure);
            app.MiscPanel.Title = 'Misc';
            app.BeepButton = uibutton(app.MiscPanel, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            app.BatterVoltageLabel = uilabel(app.MiscPanel);
            app.BatterVoltageLabel.Text = 'Batter Voltage';
            app.vLabel = uilabel(app.MiscPanel);
            app.vLabel.Text = '99.9 v';
            app.UpdateVoltageButton = uibutton(app.MiscPanel, 'push');
            app.UpdateVoltageButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVoltageButtonPushed, true);
            app.UpdateVoltageButton.Text = 'Update Voltage';
            app.SensorStreamingPanel = uipanel(app.Lab5CarMQTTGUIUIFigure);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.SensorStreamingSwitchLabel = uilabel(app.SensorStreamingPanel);
            app.SensorStreamingSwitchLabel.Text = 'Sensor Streaming';
            app.SensorStreamingSwitch = uiswitch(app.SensorStreamingPanel, 'slider');
            app.SensorStreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorStreamingSwitchValueChanged, true);
            app.LeftLampLabel = uilabel(app.SensorStreamingPanel);
            app.LeftLampLabel.Text = 'Left';
            app.LeftLamp = uilamp(app.SensorStreamingPanel);
            app.MiddleLampLabel = uilabel(app.SensorStreamingPanel);
            app.MiddleLampLabel.Text = 'Middle';
            app.MiddleLamp = uilamp(app.SensorStreamingPanel);
            app.RightLampLabel = uilabel(app.SensorStreamingPanel);
            app.RightLampLabel.Text = 'Right';
            app.RightLamp = uilamp(app.SensorStreamingPanel);
            app.TimestampLabel = uilabel(app.SensorStreamingPanel);
            app.TimestampLabel.Text = 'Timestamp';
            app.ModesPanel = uipanel(app.Lab5CarMQTTGUIUIFigure);
            app.ModesPanel.Title = 'Modes';
            app.DriveUntilWallButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilWallButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilWallButtonPushed, true);
            app.DriveUntilWallButton.Text = 'Drive Until Wall';
            app.DriveUntilLineButton = uibutton(app.ModesPanel, 'push');
            app.DriveUntilLineButton.ButtonPushedFcn = createCallbackFcn(app, @DriveUntilLineButtonPushed, true);
            app.DriveUntilLineButton.Text = 'Drive Until Line';
            app.StartLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLineFollowingButtonPushed, true);
            app.StartLineFollowingButton.Text = 'Start Line Following';
            app.StopLineFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLineFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLineFollowingButtonPushed, true);
            app.StopLineFollowingButton.Text = 'Stop Line Following';
            app.StartLightFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StartLightFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLightFollowingButtonPushed, true);
            app.StartLightFollowingButton.Text = 'Start Light Following';
            app.StopLightFollowingButton = uibutton(app.ModesPanel, 'push');
            app.StopLightFollowingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLightFollowingButtonPushed, true);
            app.StopLightFollowingButton.Text = 'Stop Light Following';
            % Show the figure after all components are created
            app.Lab5CarMQTTGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab5CarMQTTGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab5CarMQTTGUIUIFigure)
        end
    end
end