classdef gold_guesser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GoldGuesserUIFigure  matlab.ui.Figure
        FeedbackMessage      matlab.ui.control.Label
        Position3Button      matlab.ui.control.Button
        Position2Button      matlab.ui.control.Button
        Position1Button      matlab.ui.control.Button
        GoldImage            matlab.ui.control.Image
        LeprechaunImage      matlab.ui.control.Image
        SuccessValue         matlab.ui.control.Label
        AttemptValue         matlab.ui.control.Label
        SuccessLabel         matlab.ui.control.Label
        AttemptsLabel        matlab.ui.control.Label
        Background           matlab.ui.control.Image
    end

    
    properties (Access = private)
        attemptsCounter = 0
        successCounter = 0
        leprechaunPositions
        goldPositions
    end
    
    methods (Access = private)
        
        function makeAGuess(app, location)
            app.attemptsCounter = app.attemptsCounter + 1;
            % TODO: Move the images, randomize the gold, score the round
            fprintf("Pressed location %d\n", location);
            app.LeprechaunImage.Position = app.leprechaunPositions(location, :);
            goldPosition = randi([1, 3]);
            app.GoldImage.Position = app.goldPositions(goldPosition, :);
            if location == goldPosition
                app.successCounter = app.successCounter + 1;
                app.FeedbackMessage.Text = "You Found the Gold!";
            else
                app.FeedbackMessage.Text = "Unlucky";
            end
            app.AttemptValue.Text = string(app.attemptsCounter);
            app.SuccessValue.Text = string(app.successCounter);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            fprintf("Ready\n");

            app.leprechaunPositions(1, :) = [44, 167, 176, 212];
            app.leprechaunPositions(2, :) = [302, 167, 176, 212];
            app.leprechaunPositions(3, :) = [562, 167, 176, 212];

            app.goldPositions(1, :) = [82, 15, 100, 100];
            app.goldPositions(2, :) = [351, 15, 100, 100];
            app.goldPositions(3, :) = [615, 15, 100, 100];


        end

        % Button pushed function: Position1Button
        function Position1ButtonPushed(app, event)
            app.makeAGuess(1);
        end

        % Button pushed function: Position3Button
        function Position3ButtonPushed(app, event)
            app.makeAGuess(3);
        end

        % Button pushed function: Position2Button
        function Position2ButtonPushed(app, event)
            app.makeAGuess(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GoldGuesserUIFigure = uifigure('Visible', 'off');
            app.GoldGuesserUIFigure.Name = 'Gold Guesser';
            app.Background = uiimage(app.GoldGuesserUIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'background.png');
            app.AttemptsLabel = uilabel(app.GoldGuesserUIFigure);
            app.AttemptsLabel.Text = 'Attempts:';
            app.SuccessLabel = uilabel(app.GoldGuesserUIFigure);
            app.SuccessLabel.Text = 'Success:';
            app.AttemptValue = uilabel(app.GoldGuesserUIFigure);
            app.AttemptValue.Text = '0';
            app.SuccessValue = uilabel(app.GoldGuesserUIFigure);
            app.SuccessValue.Text = '0';
            app.LeprechaunImage = uiimage(app.GoldGuesserUIFigure);
            app.LeprechaunImage.ImageSource = fullfile(pathToMLAPP, 'leprechaun.png');
            app.GoldImage = uiimage(app.GoldGuesserUIFigure);
            app.GoldImage.ImageSource = fullfile(pathToMLAPP, 'gold.png');
            app.Position1Button = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Position1Button.ButtonPushedFcn = createCallbackFcn(app, @Position1ButtonPushed, true);
            app.Position1Button.Text = '1';
            app.Position2Button = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Position2Button.ButtonPushedFcn = createCallbackFcn(app, @Position2ButtonPushed, true);
            app.Position2Button.Text = '2';
            app.Position3Button = uibutton(app.GoldGuesserUIFigure, 'push');
            app.Position3Button.ButtonPushedFcn = createCallbackFcn(app, @Position3ButtonPushed, true);
            app.Position3Button.Text = '3';
            app.FeedbackMessage = uilabel(app.GoldGuesserUIFigure);
            app.FeedbackMessage.Text = '';
            % Show the figure after all components are created
            app.GoldGuesserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gold_guesser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GoldGuesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GoldGuesserUIFigure)
        end
    end
end